classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LoadGymDataFileLabel            matlab.ui.control.Label
        PressHereButton_2               matlab.ui.control.Button
        EcoGymEnergyTrackerLabel        matlab.ui.control.Label
        EfficiencyRatingEditField_2     matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        TotalRuntimeTodayhrsEditField   matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        PowerOutputWattsEditField       matlab.ui.control.NumericEditField
        PowerOutputWattsEditFieldLabel  matlab.ui.control.Label
        MachineTypeDropDown             matlab.ui.control.DropDown
        MachineTypeDropDownLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        PressHereButton                 matlab.ui.control.Button
        EcoScoreLamp                    matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        Gauge                           matlab.ui.control.Gauge
        GaugeLabel                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AnalyticsTab                    matlab.ui.container.Tab
        TotalMoneySavedEditField        matlab.ui.control.NumericEditField
        TotalMoneySavedLabel            matlab.ui.control.Label
        UIAxesSavings                   matlab.ui.control.UIAxes
        MachineCompareAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MachineTypeDropDown
        function MachineTypeDropDownValueChanged(app, event)
            selectedMachine = app.MachineTypeDropDown.Value;
            if strcmp(selectedMachine, 'Treadmill 1')
                app.PowerOutputEditField.Value = 250;  % 250 Watts
                app.EfficiencyEditField.Value = 85;    % 85% efficient
            elseif strcmp(selectedMachine, 'Spin Bike 1')
                app.PowerOutputEditField.Value = 120;  % 120 Watts
                app.EfficiencyEditField.Value = 95;    % 95% efficient
            else
                app.PowerOutputEditField.Value = 0;
                app.EfficiencyEditField.Value = 0;
            end
        end

        % Button pushed function: PressHereButton
        function PressHereButtonPushed(app, event)
            app.Gauge.Value = 450; 
        end

        % Button pushed function: PressHereButton_2
        function PressHereButton_2Pushed(app, event)
            [fileName, folderPath] = uigetfile({'*.csv;*.xlsx;*.mat', 'Gym Data Files (*.csv, *.xlsx, *.mat)'});

            % 2. Check if the user actually picked a file or hit cancel
            if fileName ~= 0
                % Create the full file pathway string
                fullPath = fullfile(folderPath, fileName);

                % Update your UI label to show the file was successfully loaded
                app.FilePathLabel.Text = ['Loaded: ' fileName];

                % --- PLACEHOLDER FOR TEAMMATE ---
                % Your teammate will insert their data analysis formulas here:
                % app.importedData = readtable(fullPath); 
                % ---------------------------------
            end
        end

        % Button down function: UIAxesSavings
        function UIAxesSavingsButtonDown(app, event)
            function updateSavingsPlot(app)
                % 1. Dummy historical data (e.g., last 6 months)
                months = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun'};

                % Energy consumed by the gym (kWh)
                energy_consumed = [1200, 1150, 1100, 1050, 1000, 950]; 

                % Energy generated by your smart bikes/treadmills (kWh)
                energy_generated = [150, 200, 250, 280, 310, 350]; 

                % 2. Financial rate ($0.15 per kWh)
                utility_rate = 0.15; 

                % 3. Calculate "Before" vs "After" costs
                before_eco_cost = energy_consumed * utility_rate;
                after_eco_cost = (energy_consumed - energy_generated) * utility_rate;

                % Net savings progress over time
                cumulative_savings = cumsum(before_eco_cost - after_eco_cost);

                % 4. Plot a grouped bar chart on your App's UIAxes
                % We combine the data into a matrix: [Before, After] for each month
                chart_data = [before_eco_cost', after_eco_cost'];

                % Clear axes and plot
                cla(app.UIAxesSavings);
                b = bar(app.UIAxesSavings, chart_data, 'grouped');

                % Apply some modern styling to the bars
                b(1).FaceColor = [0.85, 0.325, 0.098]; % Red/Orange for "Before" (Expensive)
                b(2).FaceColor = [0.466, 0.674, 0.188]; % Green for "After" (Eco-Savings!)

                % Configure axes labels and legend
                xticklabels(app.UIAxesSavings, months);
                ylabel(app.UIAxesSavings, 'Monthly Utility Cost ($)');
                title(app.UIAxesSavings, 'Gym Utility Costs: Before vs. After Eco-Smart Equipment');
                legend(app.UIAxesSavings, {'Before Eco-Smart', 'After Eco-Smart'}, 'Location', 'northeast');
                grid(app.UIAxesSavings, 'on');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {540, 540};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MachineTypeDropDownLabel = uilabel(app.LeftPanel);
            app.MachineTypeDropDownLabel.Text = 'Machine Type';
            app.MachineTypeDropDown = uidropdown(app.LeftPanel);
            app.MachineTypeDropDown.Items = {'Treadmill 1', 'Treadmill 2', 'Treadmill 3', 'Spin Bike 1', 'Spin Bike 2', 'Spin Bike 3'};
            app.MachineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MachineTypeDropDownValueChanged, true);
            app.MachineTypeDropDown.Value = 'Treadmill 1';
            app.PowerOutputWattsEditFieldLabel = uilabel(app.LeftPanel);
            app.PowerOutputWattsEditFieldLabel.Text = 'Power Output (Watts)';
            app.PowerOutputWattsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField2Label = uilabel(app.LeftPanel);
            app.EditField2Label.Text = 'Total Runtime Today (Hours)';
            app.TotalRuntimeTodayhrsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField3Label = uilabel(app.LeftPanel);
            app.EditField3Label.Text = 'Efficiency Rating (%)';
            app.EfficiencyRatingEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EcoGymEnergyTrackerLabel = uilabel(app.LeftPanel);
            app.EcoGymEnergyTrackerLabel.Text = 'Eco-Gym Energy Tracker';
            app.PressHereButton_2 = uibutton(app.LeftPanel, 'push');
            app.PressHereButton_2.ButtonPushedFcn = createCallbackFcn(app, @PressHereButton_2Pushed, true);
            app.PressHereButton_2.Text = 'Press Here';
            app.LoadGymDataFileLabel = uilabel(app.LeftPanel);
            app.LoadGymDataFileLabel.Text = 'Load Gym Data File';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.UIAxes = uiaxes(app.DashboardTab);
            title(app.UIAxes, 'Live Energy (24 hours)')
            xlabel(app.UIAxes, 'Time (Hours)')
            ylabel(app.UIAxes, 'Energy (Watts)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.25 0.5 0.75 1];
            app.UIAxes.XTickLabel = {'0'; '6'; '12'; '18'; '24'};
            app.UIAxes.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.GaugeLabel = uilabel(app.DashboardTab);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.DashboardTab, 'circular');
            app.Gauge.Limits = [0 1000];
            app.Gauge.Value = 350;
            app.StatusLabel = uilabel(app.DashboardTab);
            app.StatusLabel.Text = 'Eco-Score:';
            app.EcoScoreLamp = uilamp(app.DashboardTab);
            app.PressHereButton = uibutton(app.DashboardTab, 'push');
            app.PressHereButton.ButtonPushedFcn = createCallbackFcn(app, @PressHereButtonPushed, true);
            app.PressHereButton.Text = 'Press Here';
            app.AnalyticsTab = uitab(app.TabGroup);
            app.AnalyticsTab.Title = 'Analytics';
            app.MachineCompareAxes = uiaxes(app.AnalyticsTab);
            title(app.MachineCompareAxes, 'Energy Breakdown by Machine Type (bar) consumed vs generated')
            xlabel(app.MachineCompareAxes, 'X')
            ylabel(app.MachineCompareAxes, 'Y')
            zlabel(app.MachineCompareAxes, 'Z')
            app.UIAxesSavings = uiaxes(app.AnalyticsTab);
            title(app.UIAxesSavings, 'Savings')
            xlabel(app.UIAxesSavings, 'X')
            ylabel(app.UIAxesSavings, 'Y')
            zlabel(app.UIAxesSavings, 'Z')
            app.UIAxesSavings.ButtonDownFcn = createCallbackFcn(app, @UIAxesSavingsButtonDown, true);
            app.TotalMoneySavedLabel = uilabel(app.AnalyticsTab);
            app.TotalMoneySavedLabel.Text = 'Total Money Saved $';
            app.TotalMoneySavedEditField = uieditfield(app.AnalyticsTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end